classdef calculatorr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalculatorLabel              matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ResultEditField              matlab.ui.control.NumericEditField
        ResultEditFieldLabel         matlab.ui.control.Label
        EqualButton                  matlab.ui.control.Button
        arithmeticoperationDropDown  matlab.ui.control.DropDown
        arithmeticoperationDropDownLabel  matlab.ui.control.Label
        oprand2EditField             matlab.ui.control.NumericEditField
        oprand2EditFieldLabel        matlab.ui.control.Label
        oprand1EditField             matlab.ui.control.NumericEditField
        oprand1EditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            a=app.oprand1EditField.Value;
            b=app.oprand2EditField.Value;
            
            switch app.arithmeticoperationDropDown.Value
                case '+'
                    Result=a+b;
                    app.ResultEditField.Value=Result;
                case '-'
                    Result=a-b;
                    app.ResultEditField.Value=Result;
                case '*'
                    Result=a*b;
                    app.ResultEditField.Value=Result;
                 case '/'
                   if b==0
                    % Display an error message
                    errordlg('Error cannot divide by zero', 'Error', 'modal');
                      else
                    Result=a/b;
                    app.ResultEditField.Value=Result;
                   end
            end
        end

        % Callback function
        function Matrix_ResultButtonPushed(app, event)
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.oprand1EditFieldLabel = uilabel(app.UIFigure);
            app.oprand1EditFieldLabel.Text = 'oprand1';
            app.oprand1EditField = uieditfield(app.UIFigure, 'numeric');
            app.oprand2EditFieldLabel = uilabel(app.UIFigure);
            app.oprand2EditFieldLabel.Text = 'oprand2';
            app.oprand2EditField = uieditfield(app.UIFigure, 'numeric');
            app.arithmeticoperationDropDownLabel = uilabel(app.UIFigure);
            app.arithmeticoperationDropDownLabel.Text = 'arithmetic operation';
            app.arithmeticoperationDropDown = uidropdown(app.UIFigure);
            app.arithmeticoperationDropDown.Items = {'+', '-', '*', '/'};
            app.arithmeticoperationDropDown.Value = '/';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.CalculatorLabel = uilabel(app.UIFigure);
            app.CalculatorLabel.Text = '*Calculator*';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculatorr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end