classdef Image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GetImageButton       matlab.ui.control.Button
        InputEditField       matlab.ui.control.NumericEditField
        InputEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            a=app.InputEditField.Value;
            if a > 60
                imageToShow = imread('E:\APPS\how-to-live-a-happier-life.jpg');
                imshow(imageToShow, 'Parent', app.UIAxes);
            else
                imageToShow = imread('E:\Books\images.jpg');
                imshow(imageToShow, 'Parent', app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'numeric');
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end